classdef app_middlehw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        th               matlab.ui.control.Spinner
        Label_2          matlab.ui.control.Label
        name             matlab.ui.control.Label
        de_value         matlab.ui.control.Spinner
        Label            matlab.ui.control.Label
        subtitle_num     matlab.ui.control.Label
        subtitle_behind  matlab.ui.control.Label
        subtitle_before  matlab.ui.control.Label
        title_label      matlab.ui.control.Label
        start_button     matlab.ui.control.Button
        img_show         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.img_show,'off');  %不显示坐标轴
            title(app.img_show,'');    % 不显示标题
            xlabel(app.img_show,'')  % 不显示X轴标签
            ylabel(app.img_show,'')   % 不显示Y轴标签
            img = imread("image.png");
            imshow(img,'Parent',app.img_show);
        end

        % Button pushed function: start_button
        function start_buttonButtonPushed(app, event)
            %% 初始阶段
            img = imread("image.png");
            img_gray=rgb2gray(img);% 灰度化
            % 中值滤波
            img_gray_filt_med=medfilt2(img_gray,[3 3]);
            % 自适应直方图
            img_gray_filt_med_adapt = adapthisteq(img_gray_filt_med);
            % 二值化
            img_gray_filt_med_adapt_bin = Bersen(img_gray_filt_med_adapt, 2);
            %删除面积小的联通域
            img_gray_filt_med_adapt_bin_bware= bwareaopen(img_gray_filt_med_adapt_bin ,500);
            %% 模式匹配
            % 取模板
            img_model = img_gray_filt_med_adapt_bin_bware(70:190,10:40);
            % 匹配
            location=normxcorr2(img_model,img_gray_filt_med_adapt_bin_bware);
            %生成坐标
            thshold=app.th.Value;
            [y,x] = find(location>thshold );
            % 去重
            xy=deduplication([x,y], app.de_value.Value);
            x = xy(:, 1);
            y = xy(:, 2);
            % 坐标换算
            yoffSet = y-size(img_model,1);
            xoffSet = x-size(img_model,2);
            index=zeros(size(xoffSet,1),2);
            index(:,1)=xoffSet;
            index(:,2)=yoffSet;
            %显示定位的坐标
            figure
            imshow(img);
            for i=1:size(yoffSet,1)
                drawrectangle(gca,'Position',[index(i,1),index(i,2),size(img_model,2),size(img_model,1)], ...
                    'FaceAlpha',0);
            end
            img_ans = gca;
            exportgraphics(img_ans, "ans.png");
            img = imread("ans.png");
            imshow(img,'Parent',app.img_show);
            %% 导出到excel
            out(index);
            app.subtitle_num.Text = num2str(size(index,1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.img_show = uiaxes(app.UIFigure);
            title(app.img_show, 'Title')
            xlabel(app.img_show, 'X')
            ylabel(app.img_show, 'Y')
            zlabel(app.img_show, 'Z')
            app.start_button = uibutton(app.UIFigure, 'push');
            app.start_button.ButtonPushedFcn = createCallbackFcn(app, @start_buttonButtonPushed, true);
            app.start_button.Text = '开始识别';
            app.title_label = uilabel(app.UIFigure);
            app.title_label.Text = '隧道封顶块识别';
            app.subtitle_before = uilabel(app.UIFigure);
            app.subtitle_before.Text = '一共识别到:';
            app.subtitle_behind = uilabel(app.UIFigure);
            app.subtitle_behind.Text = '个';
            app.subtitle_num = uilabel(app.UIFigure);
            app.subtitle_num.Text = '0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '去重阈值';
            app.de_value = uispinner(app.UIFigure);
            app.de_value.Value = 10;
            app.name = uilabel(app.UIFigure);
            app.name.Text = {'By ChenAo'; '2024.6.24'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '识别阈值';
            app.th = uispinner(app.UIFigure);
            app.th.Value = 0.6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_middlehw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end