classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        path            matlab.ui.control.TextArea
        Label_4         matlab.ui.control.Label
        num_show        matlab.ui.control.TextArea
        num_txt         matlab.ui.control.Label
        title_txt       matlab.ui.control.Label
        start           matlab.ui.control.Button
        de_rate         matlab.ui.control.Spinner
        Spinner_3Label  matlab.ui.control.Label
        max             matlab.ui.control.Spinner
        Spinner_2Label  matlab.ui.control.Label
        min             matlab.ui.control.Spinner
        Label_3         matlab.ui.control.Label
        img_show        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: start
        function startButtonPushed(app, event)
            axis(app.img_show,'off');  %不显示坐标轴
            title(app.img_show,'');    % 不显示标题
            xlabel(app.img_show,'')  % 不显示X轴标签
            ylabel(app.img_show,'')   % 不显示Y轴标签
            img = imread(cell2mat(app.path.Value));
            % img = imresize(img, 0.25);
            img_r = img(:, :, 1);
            img_g = img(:, :, 2);
            img_b = img(:, :, 3);
            img_rb = img_g - img_b;
            img_rb_bin = imbinarize(img_rb, 8/255);
            img_rb_bin_fill=imfill(img_rb_bin,'holes');
            Obj=uint8(img_rb_bin_fill) .* img * 1.2;
            imshow(Obj);

            %圆木计数代码
            [centers, radii] = imfindcircles(img_b,[app.min.Value app.max.Value],'ObjectPolarity','bright',...
                'Sensitivity',0.97);
            [centers, radii] = deduplication(centers, radii, app.de_rate.Value);
            h = viscircles(centers,radii);
            img_ans = gca;
            exportgraphics(img_ans, "ans.png");
            img = imread("ans.png");
            imshow(img,'Parent',app.img_show);
            app.num_show.Value = num2str(length(centers));
   
        end

        % Button down function: img_show
        function img_showButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.img_show = uiaxes(app.UIFigure);
            title(app.img_show, 'Title')
            xlabel(app.img_show, 'X')
            ylabel(app.img_show, 'Y')
            zlabel(app.img_show, 'Z')
            app.img_show.ButtonDownFcn = createCallbackFcn(app, @img_showButtonDown, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '原木直径最小值';
            app.min = uispinner(app.UIFigure);
            app.Spinner_2Label = uilabel(app.UIFigure);
            app.Spinner_2Label.Text = '原木直径最大值';
            app.max = uispinner(app.UIFigure);
            app.Spinner_3Label = uilabel(app.UIFigure);
            app.Spinner_3Label.Text = '去重率';
            app.de_rate = uispinner(app.UIFigure);
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '开始计数';
            app.title_txt = uilabel(app.UIFigure);
            app.title_txt.Text = '原木识别';
            app.num_txt = uilabel(app.UIFigure);
            app.num_txt.Text = '原木数目为';
            app.num_show = uitextarea(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '图片路径';
            app.path = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end